classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        peaksButton            matlab.ui.control.Button
        RibbonLabel            matlab.ui.control.Label
        WaterfallLabel         matlab.ui.control.Label
        FunctiiButtonGroup     matlab.ui.container.ButtonGroup
        RibbonButton           matlab.ui.control.RadioButton
        WaterfallButton        matlab.ui.control.RadioButton
        StartButton            matlab.ui.control.Button
        zEditField             matlab.ui.control.EditField
        zEditFieldLabel        matlab.ui.control.Label
        yEditField             matlab.ui.control.EditField
        yEditFieldLabel        matlab.ui.control.Label
        xEditField             matlab.ui.control.EditField
        xEditFieldLabel        matlab.ui.control.Label
        XYZvaluesLabel         matlab.ui.control.Label
        RibbonwidthvalueLabel  matlab.ui.control.Label
        widthSlider            matlab.ui.control.Slider
        widthSliderLabel       matlab.ui.control.Label
        UIAxesRibbon           matlab.ui.control.UIAxes
        UIAxesWaterfall        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = [];
        y = [];
        z = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.x = str2num(app.xEditField.Value)
            app.y = str2num(app.yEditField.Value)
            app.z = str2num(app.zEditField.Value);
            width = app.widthSlider.Value;          

            if app.WaterfallButton.Value
                [x,y] = meshgrid(app.x,app.y);
                app.z = eval(app.zEditField.Value) %get z function
                waterfall(app.UIAxesWaterfall,x,y,app.z);
            else 
                [x,y] = meshgrid(app.x,app.y);
                app.z = peaks(x,y);
                ribbon(app.UIAxesRibbon,y,app.z,width);
            end


        end

        % Selection changed function: FunctiiButtonGroup
        function FunctiiButtonGroupSelectionChanged(app, event)
            selectedButton = app.FunctiiButtonGroup.SelectedObject;
            if app.RibbonButton.Value
                app.zEditField.Enable = 'off';
                app.zEditField.Editable = 'off';
            else
                app.zEditField.Enable = 'on';
                app.zEditField.Editable = 'on';
            end

        end

        % Button pushed function: peaksButton
        function peaksButtonPushed(app, event)
         app.x = str2num(app.xEditField.Value);
         app.y = str2num(app.yEditField.Value); 

         [x,y] = meshgrid(app.x,app.y);
         app.z = peaks(x,y);

         waterfall(app.UIAxesWaterfall,x,y,app.z);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesWaterfall = uiaxes(app.UIFigure);
            xlabel(app.UIAxesWaterfall, 'X')
            ylabel(app.UIAxesWaterfall, 'Y')
            zlabel(app.UIAxesWaterfall, 'Z')
            app.UIAxesRibbon = uiaxes(app.UIFigure);
            xlabel(app.UIAxesRibbon, 'X')
            ylabel(app.UIAxesRibbon, 'Y')
            zlabel(app.UIAxesRibbon, 'Z')
            app.widthSliderLabel = uilabel(app.UIFigure);
            app.widthSliderLabel.Text = 'width';
            app.widthSlider = uislider(app.UIFigure);
            app.widthSlider.Limits = [0 10];
            app.RibbonwidthvalueLabel = uilabel(app.UIFigure);
            app.RibbonwidthvalueLabel.Text = 'Ribbon(width) value';
            app.XYZvaluesLabel = uilabel(app.UIFigure);
            app.XYZvaluesLabel.Text = 'X,Y,Z values';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FunctiiButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctiiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FunctiiButtonGroupSelectionChanged, true);
            app.FunctiiButtonGroup.Title = 'Functii';
            app.WaterfallButton = uiradiobutton(app.FunctiiButtonGroup);
            app.WaterfallButton.Text = 'Waterfall';
            app.WaterfallButton.Value = true;
            app.RibbonButton = uiradiobutton(app.FunctiiButtonGroup);
            app.RibbonButton.Text = 'Ribbon';
            app.WaterfallLabel = uilabel(app.UIFigure);
            app.WaterfallLabel.Text = 'Waterfall';
            app.RibbonLabel = uilabel(app.UIFigure);
            app.RibbonLabel.Text = 'Ribbon';
            app.peaksButton = uibutton(app.UIFigure, 'push');
            app.peaksButton.ButtonPushedFcn = createCallbackFcn(app, @peaksButtonPushed, true);
            app.peaksButton.Text = 'peaks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end