classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        yEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel  matlab.ui.control.Label
        zEditField       matlab.ui.control.EditField
        zEditFieldLabel  matlab.ui.control.Label
        Tree             matlab.ui.container.Tree
        Node             matlab.ui.container.TreeNode
        trashNode        matlab.ui.container.TreeNode
        Node3            matlab.ui.container.TreeNode
        Node4            matlab.ui.container.TreeNode
        Node2_2          matlab.ui.container.TreeNode
        Node_2           matlab.ui.container.TreeNode
        Node3_2          matlab.ui.container.TreeNode
        Node_3           matlab.ui.container.TreeNode
        Node4_2          matlab.ui.container.TreeNode
        Node_4           matlab.ui.container.TreeNode
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes.Text;
            if strcmpi(selectedNodes,'trash')
                app.zEditField.Enable = 'off';
                app.zEditField.Editable = 'off'; 
            else
                app.zEditField.Enable = 'on';
                app.zEditField.Editable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.trashNode = uitreenode(app.Node);
            app.trashNode.Text = 'trash';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Node2_2 = uitreenode(app.Tree);
            app.Node2_2.Text = 'Node2';
            app.Node_2 = uitreenode(app.Node2_2);
            app.Node_2.Text = 'Node';
            app.Node3_2 = uitreenode(app.Tree);
            app.Node3_2.Text = 'Node3';
            app.Node_3 = uitreenode(app.Node3_2);
            app.Node_3.Text = 'Node';
            app.Node4_2 = uitreenode(app.Tree);
            app.Node4_2.Text = 'Node4';
            app.Node_4 = uitreenode(app.Node4_2);
            app.Node_4.Text = 'Node';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end